classdef Photo_Research < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        StatusTab                     matlab.ui.container.Tab
        HardwareconfigTextArea        matlab.ui.control.TextArea
        HardwareconfigTextAreaLabel   matlab.ui.control.Label
        SoftwareversionTextArea       matlab.ui.control.TextArea
        SoftwareversionTextAreaLabel  matlab.ui.control.Label
        SerialnoTextArea              matlab.ui.control.TextArea
        SerialnoTextAreaLabel         matlab.ui.control.Label
        ModelTextArea                 matlab.ui.control.TextArea
        ModelTextAreaLabel            matlab.ui.control.Label
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportDropDownLabel       matlab.ui.control.Label
        StatusTextArea                matlab.ui.control.TextArea
        CheckstatusButton             matlab.ui.control.Button
        ClearerrorButton              matlab.ui.control.Button
        ConnectButton                 matlab.ui.control.StateButton
        SetupTab                      matlab.ui.container.Tab
        MeasureTab                    matlab.ui.container.Tab
        RecallTab                     matlab.ui.container.Tab
        PlotTab                       matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SerialportDropDown
        function SerialportDropDownOpening(app, event)
            app.SerialportDropDown.Items = cellstr(serialportlist("available"));
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            do_connect = app.ConnectButton.Value;
            port_id = app.SerialportDropDown.Value;
            if do_connect
                camera = serialport(port_id,2400,"FlowControl","hardware");
                enter_remote_mode(camera);
                data = struct();
                data = parse_data(data,111,measure(camera,"data_code",111));
                data = parse_data(data,110,measure(camera,"data_code",110));
                data = parse_data(data,114,measure(camera,"data_code",114));
                data = parse_data(data,602,measure(camera,"data_code",602));
                app.ModelTextArea.Value = data.model;
                app.SerialnoTextArea.Value = data.serial_num;
                app.Softwareversion.Value = data.software_version;
                CheckstatusButtonPushed(app,event);
            else
                exit_remote_mode(camera);
                clear camera;
            end
        end

        % Button pushed function: ClearerrorButton
        function ClearerrorButtonPushed(app, event)
            clear_error(camera);
            CheckstatusButtonPushed(app,event);
        end

        % Button pushed function: CheckstatusButton
        function CheckstatusButtonPushed(app, event)
            status_msg = split(get_error_msg(get_status(camera)),"\n");
            app.StatusTextArea.Value = "";
            for line = 1:length(status_msg)
                app.StatusTextArea.Value{line} = status_msg(line);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StatusTab = uitab(app.TabGroup);
            app.StatusTab.Title = 'Status';
            app.ConnectButton = uibutton(app.StatusTab, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearerrorButton = uibutton(app.StatusTab, 'push');
            app.ClearerrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearerrorButtonPushed, true);
            app.ClearerrorButton.Text = 'Clear error';
            app.CheckstatusButton = uibutton(app.StatusTab, 'push');
            app.CheckstatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckstatusButtonPushed, true);
            app.CheckstatusButton.Text = 'Check status';
            app.StatusTextArea = uitextarea(app.StatusTab);
            app.SerialportDropDownLabel = uilabel(app.StatusTab);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.StatusTab);
            app.SerialportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialportDropDownOpening, true);
            app.ModelTextAreaLabel = uilabel(app.StatusTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.StatusTab);
            app.SerialnoTextAreaLabel = uilabel(app.StatusTab);
            app.SerialnoTextAreaLabel.Text = 'Serial no.';
            app.SerialnoTextArea = uitextarea(app.StatusTab);
            app.SoftwareversionTextAreaLabel = uilabel(app.StatusTab);
            app.SoftwareversionTextAreaLabel.Text = 'Software version';
            app.SoftwareversionTextArea = uitextarea(app.StatusTab);
            app.HardwareconfigTextAreaLabel = uilabel(app.StatusTab);
            app.HardwareconfigTextAreaLabel.Text = 'Hardware config.';
            app.HardwareconfigTextArea = uitextarea(app.StatusTab);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.MeasureTab = uitab(app.TabGroup);
            app.MeasureTab.Title = 'Measure';
            app.RecallTab = uitab(app.TabGroup);
            app.RecallTab.Title = 'Recall';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Photo_Research

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end